classdef CVModelGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Parameters1Tab                  matlab.ui.container.Tab
        ResetDefaultValuesButton        matlab.ui.control.Button
        PleaseEnterModelNameEditFieldLabel  matlab.ui.control.Label
        PleaseEnterModelNameEditField   matlab.ui.control.EditField
        ValvesPanel                     matlab.ui.container.Panel
        AVMaxOpeningAreacm2EditFieldLabel  matlab.ui.control.Label
        AVMaxOpeningAreacm2EditField    matlab.ui.control.NumericEditField
        AVLeakageAreacm2EditFieldLabel  matlab.ui.control.Label
        AVLeakageAreacm2EditField       matlab.ui.control.NumericEditField
        MVMaxOpeningAreacm2EditFieldLabel  matlab.ui.control.Label
        MVMaxOpeningAreacm2EditField    matlab.ui.control.NumericEditField
        MVLeakageAreacm2EditFieldLabel  matlab.ui.control.Label
        MVLeakageAreacm2EditField       matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        e13Label                        matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        e13Label_2                      matlab.ui.control.Label
        CalibrateSettingsPanel_2        matlab.ui.container.Panel
        ExportSettingsButton_2          matlab.ui.control.Button
        ImportSettingsButton_2          matlab.ui.control.Button
        Parameters2Tab                  matlab.ui.container.Tab
        LambdaPanel                     matlab.ui.container.Panel
        lambdarvfEditFieldLabel         matlab.ui.control.Label
        lambdarvfEditField              matlab.ui.control.NumericEditField
        lambdalaEditFieldLabel          matlab.ui.control.Label
        lambdalaEditField               matlab.ui.control.NumericEditField
        lambdaraEditFieldLabel          matlab.ui.control.Label
        lambdaraEditField               matlab.ui.control.NumericEditField
        Label_11                        matlab.ui.control.Label
        Label_15                        matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        Label_16                        matlab.ui.control.Label
        lambdapcdEditFieldLabel         matlab.ui.control.Label
        lambdapcdEditField              matlab.ui.control.NumericEditField
        lambdalvfEditFieldLabel         matlab.ui.control.Label
        lambdalvfEditField              matlab.ui.control.NumericEditField
        Label_17                        matlab.ui.control.Label
        VolumePanel                     matlab.ui.container.Panel
        V0lvfEditFieldLabel             matlab.ui.control.Label
        V0lvfEditField                  matlab.ui.control.NumericEditField
        VdrvfEditFieldLabel             matlab.ui.control.Label
        VdrvfEditField                  matlab.ui.control.NumericEditField
        V0rvfEditFieldLabel             matlab.ui.control.Label
        V0rvfEditField                  matlab.ui.control.NumericEditField
        Label_13                        matlab.ui.control.Label
        Label_18                        matlab.ui.control.Label
        Label_14                        matlab.ui.control.Label
        Label_19                        matlab.ui.control.Label
        VdlaEditFieldLabel              matlab.ui.control.Label
        VdlaEditField                   matlab.ui.control.NumericEditField
        VdlvfEditFieldLabel             matlab.ui.control.Label
        VdlvfEditField                  matlab.ui.control.NumericEditField
        V0laEditFieldLabel              matlab.ui.control.Label
        V0laEditField                   matlab.ui.control.NumericEditField
        VdraEditFieldLabel              matlab.ui.control.Label
        VdraEditField                   matlab.ui.control.NumericEditField
        V0raEditFieldLabel              matlab.ui.control.Label
        V0raEditField                   matlab.ui.control.NumericEditField
        V0pcdEditFieldLabel             matlab.ui.control.Label
        V0pcdEditField                  matlab.ui.control.NumericEditField
        Label_20                        matlab.ui.control.Label
        Label_21                        matlab.ui.control.Label
        Label_22                        matlab.ui.control.Label
        Label_23                        matlab.ui.control.Label
        Label_24                        matlab.ui.control.Label
        ResetDefaultValuesButton_2      matlab.ui.control.Button
        Parameters3Tab                  matlab.ui.container.Tab
        OtherPanel                      matlab.ui.container.Panel
        Venk1EditFieldLabel             matlab.ui.control.Label
        Venk1EditField                  matlab.ui.control.NumericEditField
        Venk2EditFieldLabel             matlab.ui.control.Label
        Venk2EditField                  matlab.ui.control.NumericEditField
        RaoEditFieldLabel               matlab.ui.control.Label
        RaoEditField                    matlab.ui.control.NumericEditField
        Label_25                        matlab.ui.control.Label
        Label_26                        matlab.ui.control.Label
        Label_27                        matlab.ui.control.Label
        Label_28                        matlab.ui.control.Label
        RsaEditFieldLabel               matlab.ui.control.Label
        RsaEditField                    matlab.ui.control.NumericEditField
        KsysEditFieldLabel              matlab.ui.control.Label
        KsysEditField                   matlab.ui.control.NumericEditField
        VusvEditFieldLabel              matlab.ui.control.Label
        VusvEditField                   matlab.ui.control.NumericEditField
        CvTotalEditFieldLabel           matlab.ui.control.Label
        CvTotalEditField                matlab.ui.control.NumericEditField
        Label_29                        matlab.ui.control.Label
        Label_30                        matlab.ui.control.Label
        Label_31                        matlab.ui.control.Label
        ResetDefaultValuesButton_3      matlab.ui.control.Button
        ElastancePanel_2                matlab.ui.container.Panel
        EpaEditField_2Label             matlab.ui.control.Label
        EpaEditField_2                  matlab.ui.control.NumericEditField
        EpuEditField_2Label             matlab.ui.control.Label
        EpuEditField_2                  matlab.ui.control.NumericEditField
        EaoEditField_2Label             matlab.ui.control.Label
        EaoEditField_2                  matlab.ui.control.NumericEditField
        EeslvfEditField_2Label          matlab.ui.control.Label
        EeslvfEditField_2               matlab.ui.control.NumericEditField
        EesrvfEditField_2Label          matlab.ui.control.Label
        EesrvfEditField_2               matlab.ui.control.NumericEditField
        EeslaEditField_2Label           matlab.ui.control.Label
        EeslaEditField_2                matlab.ui.control.NumericEditField
        EesraEditField_2Label           matlab.ui.control.Label
        EesraEditField_2                matlab.ui.control.NumericEditField
        EsaEditField_2Label             matlab.ui.control.Label
        EsaEditField_2                  matlab.ui.control.NumericEditField
        Label_32                        matlab.ui.control.Label
        Label_33                        matlab.ui.control.Label
        Label_34                        matlab.ui.control.Label
        Label_35                        matlab.ui.control.Label
        Label_36                        matlab.ui.control.Label
        Label_37                        matlab.ui.control.Label
        Label_38                        matlab.ui.control.Label
        Label_39                        matlab.ui.control.Label
        Results1Tab                     matlab.ui.container.Tab
        ApplyButton_2                   matlab.ui.control.Button
        P_RVEDPTextAreaLabel            matlab.ui.control.Label
        P_RVEDPTextArea                 matlab.ui.control.TextArea
        P_RVmaxTextAreaLabel            matlab.ui.control.Label
        P_RVmaxTextArea                 matlab.ui.control.TextArea
        RVESVTextAreaLabel              matlab.ui.control.Label
        RVESVTextArea                   matlab.ui.control.TextArea
        RVEDVTextAreaLabel              matlab.ui.control.Label
        RVEDVTextArea                   matlab.ui.control.TextArea
        RV_SVTextAreaLabel              matlab.ui.control.Label
        RV_SVTextArea                   matlab.ui.control.TextArea
        RV_EFTextAreaLabel              matlab.ui.control.Label
        RV_EFTextArea                   matlab.ui.control.TextArea
        P_RVEDP1TextAreaLabel           matlab.ui.control.Label
        P_RVEDP1TextArea                matlab.ui.control.TextArea
        P_RVmax1TextAreaLabel           matlab.ui.control.Label
        P_RVmax1TextArea                matlab.ui.control.TextArea
        P_RAminTextAreaLabel            matlab.ui.control.Label
        P_RAminTextArea                 matlab.ui.control.TextArea
        P_RAmaxTextAreaLabel            matlab.ui.control.Label
        P_RAmaxTextArea                 matlab.ui.control.TextArea
        Vol_RAminTextAreaLabel          matlab.ui.control.Label
        Vol_RAminTextArea               matlab.ui.control.TextArea
        Vol_RAmaxTextAreaLabel          matlab.ui.control.Label
        Vol_RAmaxTextArea               matlab.ui.control.TextArea
        AVATextAreaLabel                matlab.ui.control.Label
        AVATextArea                     matlab.ui.control.TextArea
        MR_EROTextAreaLabel             matlab.ui.control.Label
        MR_EROTextArea                  matlab.ui.control.TextArea
        LVETTextAreaLabel               matlab.ui.control.Label
        LVETTextArea                    matlab.ui.control.TextArea
        P_LAminTextAreaLabel            matlab.ui.control.Label
        P_LAminTextArea                 matlab.ui.control.TextArea
        P_LAmaxTextAreaLabel            matlab.ui.control.Label
        P_LAmaxTextArea                 matlab.ui.control.TextArea
        meanLAPTextAreaLabel            matlab.ui.control.Label
        meanLAPTextArea                 matlab.ui.control.TextArea
        Vol_LAminTextAreaLabel          matlab.ui.control.Label
        Vol_LAminTextArea               matlab.ui.control.TextArea
        Vol_LAmaxTextAreaLabel          matlab.ui.control.Label
        Vol_LAmaxTextArea               matlab.ui.control.TextArea
        Results2Tab                     matlab.ui.container.Tab
        DBPTextAreaLabel                matlab.ui.control.Label
        DBPTextArea                     matlab.ui.control.TextArea
        SBPTextAreaLabel                matlab.ui.control.Label
        SBPTextArea                     matlab.ui.control.TextArea
        P_LVEDPTextAreaLabel            matlab.ui.control.Label
        P_LVEDPTextArea                 matlab.ui.control.TextArea
        P_LVmaxTextAreaLabel            matlab.ui.control.Label
        P_LVmaxTextArea                 matlab.ui.control.TextArea
        mLVEDPTextAreaLabel             matlab.ui.control.Label
        mLVEDPTextArea                  matlab.ui.control.TextArea
        LVESVTextAreaLabel              matlab.ui.control.Label
        LVESVTextArea                   matlab.ui.control.TextArea
        LVEDVTextAreaLabel              matlab.ui.control.Label
        LVEDVTextArea                   matlab.ui.control.TextArea
        LV_SVTextAreaLabel              matlab.ui.control.Label
        LV_SVTextArea                   matlab.ui.control.TextArea
        AVOF_SVTextAreaLabel            matlab.ui.control.Label
        AVOF_SVTextArea                 matlab.ui.control.TextArea
        LV_EFTextAreaLabel              matlab.ui.control.Label
        LV_EFTextArea                   matlab.ui.control.TextArea
        AVOF_EFTextAreaLabel            matlab.ui.control.Label
        AVOF_EFTextArea                 matlab.ui.control.TextArea
        CO_LVTextAreaLabel              matlab.ui.control.Label
        CO_LVTextArea                   matlab.ui.control.TextArea
        CO_AVOFTextAreaLabel            matlab.ui.control.Label
        CO_AVOFTextArea                 matlab.ui.control.TextArea
        TSWTextAreaLabel                matlab.ui.control.Label
        TSWTextArea                     matlab.ui.control.TextArea
        Pw_TSWTextAreaLabel             matlab.ui.control.Label
        Pw_TSWTextArea                  matlab.ui.control.TextArea
        ApplyButton_3                   matlab.ui.control.Button
        Results3Tab                     matlab.ui.container.Tab
        QmaxAVTextAreaLabel             matlab.ui.control.Label
        QmaxAVTextArea                  matlab.ui.control.TextArea
        QmaxLVOTTextAreaLabel           matlab.ui.control.Label
        QmaxLVOTTextArea                matlab.ui.control.TextArea
        VmaxAVTextAreaLabel             matlab.ui.control.Label
        VmaxAVTextArea                  matlab.ui.control.TextArea
        VmaxLVOTTextAreaLabel           matlab.ui.control.Label
        VmaxLVOTTextArea                matlab.ui.control.TextArea
        MPGTextAreaLabel                matlab.ui.control.Label
        MPGTextArea                     matlab.ui.control.TextArea
        MaxPGTextAreaLabel              matlab.ui.control.Label
        MaxPGTextArea                   matlab.ui.control.TextArea
        DVITextAreaLabel                matlab.ui.control.Label
        DVITextArea                     matlab.ui.control.TextArea
        ZvaTextAreaLabel                matlab.ui.control.Label
        ZvaTextArea                     matlab.ui.control.TextArea
        Vol_MVTextAreaLabel             matlab.ui.control.Label
        Vol_MVTextArea                  matlab.ui.control.TextArea
        Vol_MRTextAreaLabel             matlab.ui.control.Label
        Vol_MRTextArea                  matlab.ui.control.TextArea
        Reg_FracTextAreaLabel           matlab.ui.control.Label
        Reg_FracTextArea                matlab.ui.control.TextArea
        MV_timeTextAreaLabel            matlab.ui.control.Label
        MV_timeTextArea                 matlab.ui.control.TextArea
        MR_timeTextAreaLabel            matlab.ui.control.Label
        MR_timeTextArea                 matlab.ui.control.TextArea
        ApplyButton_4                   matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ResetDefaultValuesButton
        function ResetDefaultValuesButtonPushed(app, event)
            %Valve Parameters
            app.AVMaxOpeningAreacm2EditField.Value = 4;
            app.AVLeakageAreacm2EditField.Value = 1e-13;
            app.MVMaxOpeningAreacm2EditField.Value = 5;
            app.MVLeakageAreacm2EditField.Value = 1e-13;

                        
        end

        % Button pushed function: ApplyButton_2, ApplyButton_3, 
        % ApplyButton_4
        function ApplyButton_2Pushed(app, event)
            modelname = app.PleaseEnterModelNameEditField.Value;
            open_system(modelname)
            
            %Valve Parameters
            set_param(append(modelname,'/Left Heart/AV_MV_IL/Aortic Valve'),...
               'area_max', num2str(app.AVMaxOpeningAreacm2EditField.Value))
            set_param(append(modelname,'/Left Heart/AV_MV_IL/Aortic Valve'),...
               'area_leak', num2str(app.AVLeakageAreacm2EditField.Value))
            set_param(append(modelname,'/Left Heart/AV_MV_IL/Mitral Valve'),...
               'area_max', num2str(app.MVMaxOpeningAreacm2EditField.Value))
            set_param(append(modelname,'/Left Heart/AV_MV_IL/Mitral Valve'),...
               'area_leak', num2str(app.MVLeakageAreacm2EditField.Value))
            
            
            %Elastance Parameters
            set_param(append(modelname,'/Systemic/Gain4'),'Gain',...
                num2str(app.EsaEditField_2.Value))
            set_param(append(modelname,'/Pulmonary/Gain'),'Gain',...
                num2str(app.EpaEditField_2.Value))
            set_param(append(modelname,'/Pulmonary/Gain2'),'Gain',...
                num2str(app.EpuEditField_2.Value))
            set_param(append(modelname,'/Systemic/PV-aorta/Gain'),'Gain',...
                num2str(app.EaoEditField_2.Value))
            set_param(append(modelname,'/Left Heart/Constant22'),'Value',...
                num2str(app.EeslvfEditField_2.Value))
            set_param(append(modelname,'/Right Heart/Constant12'),'Value',...
                num2str(app.EesrvfEditField_2.Value))
            set_param(append(modelname,'/Left Heart/Constant3'),'Value',...
                num2str(app.EeslaEditField_2.Value))
            set_param(append(modelname,'/Right Heart/Constant3'),'Value',...
                num2str(app.EesraEditField_2.Value))
            
            %Lambda Parameters
            set_param(append(modelname,'/Left Heart/Gain3'),'Gain',...
                num2str(app.lambdalvfEditField.Value))
            set_param(append(modelname,'/Left Heart/Constant17'),'Value',...
                num2str(app.lambdalvfEditField.Value))
            set_param(append(modelname,'/Right Heart/Constant11'),'Value',...
                num2str(app.lambdarvfEditField.Value))
            set_param(append(modelname,'/Right Heart/Gain3'),'Gain',...
                num2str(app.lambdarvfEditField.Value))
            
            set_param(append(modelname,'/Left Heart/Gain10'),'Gain',...
                num2str(app.lambdalaEditField.Value))
            set_param(append(modelname,'/Right Heart/Gain10'),'Gain',...
                num2str(app.lambdaraEditField.Value))
            set_param(append(modelname,'/Left Heart/Gain8'),'Gain',...
                num2str(app.lambdapcdEditField.Value))
            
            %Volume Parameters
            set_param(append(modelname,'/Left Heart/Constant6'),'Value',...
                num2str(app.VdlvfEditField.Value))
            set_param(append(modelname,'/Left Heart/Constant9'),'Value',...
                num2str(app.V0lvfEditField.Value))
            set_param(append(modelname,'/Right Heart/Constant6'),'Value',...
                num2str(app.VdrvfEditField.Value))
            set_param(append(modelname,'/Right Heart/Constant9'),'Value',...
                num2str(app.V0rvfEditField.Value))
            
            set_param(append(modelname,'/Left Heart/Constant4'),'Value',...
                num2str(app.VdlaEditField.Value))
            set_param(append(modelname,'/Left Heart/Constant1'),'Value',...
                num2str(app.V0laEditField.Value))
            set_param(append(modelname,'/Right Heart/Constant4'),'Value',...
                num2str(app.VdraEditField.Value))
            set_param(append(modelname,'/Right Heart/Constant1'),'Value',...
                num2str(app.V0raEditField.Value))
            set_param(append(modelname,'/Left Heart/Constant14'),'Value',...
                num2str(app.V0pcdEditField.Value))
            
            %Other Parameters
            set_param(append(modelname,'/ev/ksys'),'Value',...
                num2str(app.KsysEditField.Value))
            set_param(append(modelname,'/ev/venk1'),'Value',...
                num2str(app.Venk1EditField.Value))
            set_param(append(modelname,'/ev/venk2'),'Value',...
                num2str(app.Venk2EditField.Value))
            set_param(append(modelname,'/Systemic/Transfer Fcn'),'Denominator',...
                [char('['), 'Lao ', num2str(app.RaoEditField.Value), char(']')])
            
            %Rao is a bit tricky
%             set_param(append(modelname,'/Systemic/Transfer Fcn'),...
%                 'Denominator', append('[Lao ',num2str(app.RaoEditField.Value,']')))
            
            set_param(append(modelname,'/Baroreflex/No Baroreflex/Constant2'),...
                'Value', num2str(app.RsaEditField.Value))
            set_param(append(modelname,'/Baroreflex/No Baroreflex/Constant3'),...
                'Value', num2str(app.VusvEditField.Value))
            set_param(append(modelname,'/Variables to Workspace/Constant2'),...
                'Value', num2str(app.CvTotalEditField.Value))


            simout = sim(modelname);
            
            % Results 1
            app.AVATextArea.Value = num2str(AVA);
            app.MR_EROTextArea.Value = num2str(MR_ERO);
            app.LVETTextArea.Value = num2str(LVET);

            app.P_RVEDPTextArea.Value = num2str(P_RVEDP);
            app.P_RVmaxTextArea.Value = num2str(P_RVmax);
            
            app.RVESVTextArea.Value = num2str(RVESV);
            app.RVEDVTextArea.Value = num2str(RVEDV);
            
            app.RV_SVTextArea.Value = num2str(RV_SV);
            app.RV_EFTextArea.Value = num2str(RV_EF);
            
            app.P_RVEDP1TextArea.Value = num2str(P_RVEDP1);
            app.P_RVmax1TextArea.Value = num2str(P_RVmax1);
            
            app.P_RAminTextArea.Value = num2str(P_RAmin);
            app.P_RAmaxTextArea.Value = num2str(P_RAmax);
            
            app.Vol_RAminTextArea.Value = num2str(Vol_RAmin);
            app.Vol_RAmaxTextArea.Value = num2str(Vol_RAmax);
            
            app.P_LAminTextArea.Value = num2str(P_LAmin);
            app.P_LAmaxTextArea.Value = num2str(P_LAmax);
            
            app.meanLAPTextArea.Value = num2str(meanLAP);
            
            app.Vol_LAminTextArea.Value = num2str(Vol_LAmin);
            app.Vol_LAmaxTextArea.Value = num2str(Vol_LAmax);


            % Results 2
            app.DBPTextArea.Value = num2str(DBP);
            app.SBPTextArea.Value = num2str(SBP);
            
            app.P_LVEDPTextArea.Value = num2str(P_LVEDP);
            app.P_LVmaxTextArea.Value = num2str(P_LVmax);
            
            app.mLVEDPTextArea.Value = num2str(mLVEDP);
            
            app.LVESVTextArea.Value = num2str(LVESV);
            app.LVEDVTextArea.Value = num2str(LVEDV);
            
            app.LV_SVTextArea.Value = num2str(LV_SV);
            
            app.AVOF_SVTextArea.Value = num2str(AVOF_SV);
            
            app.LV_EFTextArea.Value = num2str(LV_EF);
            app.AVOF_EFTextArea.Value = num2str(AVOF_EF);
            
            app.CO_LVTextArea.Value = num2str(CO_LV);
            app.CO_AVOFTextArea.Value = num2str(CO_AVOF);
            
            app.TSWTextArea.Value = num2str(TSW);
            app.Pw_TSWTextArea.Value = num2str(Pw_TSW);
            
            
            % Results 3
            app.QmaxAVTextArea.Value = num2str(QmaxAV);
            app.QmaxLVOTTextArea.Value = num2str(QmaxLVOT);
            
            app.VmaxAVTextArea.Value = num2str(VmaxAV);
            app.VmaxLVOTTextArea.Value = num2str(VmaxLVOT);
            
            app.MPGTextArea.Value = num2str(MPG);
            app.MaxPGTextArea.Value = num2str(MaxPG);
            
            app.DVITextArea.Value = num2str(DVI);
            app.ZvaTextArea.Value = num2str(Zva);
            
            app.Vol_MVTextArea.Value = num2str(Vol_MV);
            app.Vol_MRTextArea.Value = num2str(Vol_MR);
            
            app.Reg_FracTextArea.Value = num2str(Reg_Frac);
            app.MV_timeTextArea.Value = num2str(MV_time);
            
            app.MR_timeTextArea.Value = num2str(MR_time);

        end

        % Button pushed function: ResetDefaultValuesButton_2
        function ResetDefaultValuesButton_2Pushed(app, event)
            %Lambda Parameters
            app.lambdalvfEditField.Value = 0.041;
            app.lambdarvfEditField.Value = 0.028;
            app.lambdalaEditField.Value = 0.13;
            app.lambdaraEditField.Value = 0.13;
            app.lambdapcdEditField.Value = 0.0085;
            
            %Volume Parameters
            app.VdlvfEditField.Value = 20;
            app.V0lvfEditField.Value = 40;
            app.VdrvfEditField.Value = 40.8;
            app.V0rvfEditField.Value = 50;
            app.VdlaEditField.Value = 10;
            app.V0laEditField.Value = 20;
            app.VdraEditField.Value = 10;
            app.V0raEditField.Value = 20;
            app.V0pcdEditField.Value = 200;
            
        end

        % Button pushed function: ResetDefaultValuesButton_3
        function ResetDefaultValuesButton_3Pushed(app, event)
            %Elastance Parameters
            app.EsaEditField_2.Value = 0.37;
            app.EpaEditField_2.Value = 0.15;
            app.EpuEditField_2.Value = 0.04;
            app.EaoEditField_2.Value = 1.04;
            app.EeslvfEditField_2.Value = 5;
            app.EesrvfEditField_2.Value = 1.75;
            app.EeslaEditField_2.Value = 0.63;
            app.EesraEditField_2.Value = 0.63;
            
            %Other Parameters
            app.KsysEditField.Value =0.075;
            app.Venk1EditField.Value = 0.5;
            app.Venk2EditField.Value = 0.5;
            app.RaoEditField.Value = 0.2;
            app.RsaEditField.Value = 0.74;
            app.VusvEditField.Value = 1976;
            app.CvTotalEditField.Value = 5200;
        end

        % Button pushed function: ExportSettingsButton_2
        function ExportSettingsButtonPushed(app, event)
            %Put inputs into a Matrix
            exportfile = [app.AVMaxOpeningAreacm2EditField.Value, app.AVLeakageAreacm2EditField.Value,...
                app.MVMaxOpeningAreacm2EditField.Value, app.MVLeakageAreacm2EditField.Value,...
                app.EsaEditField_2.Value, app.EpaEditField_2.Value, app.EpuEditField_2.Value,...
                app.EaoEditField_2.Value, app.EeslvfEditField_2.Value, app.EesrvfEditField_2.Value,...
                app.EeslaEditField_2.Value, app.EesraEditField_2.Value, ...
                app.lambdalvfEditField.Value, app.lambdarvfEditField.Value,...
                app.lambdalaEditField.Value, app.lambdaraEditField.Value,...
                app.lambdapcdEditField.Value,...
                app.VdlvfEditField.Value, app.V0lvfEditField.Value, app.VdrvfEditField.Value,...
                app.V0rvfEditField.Value, app.VdlaEditField.Value, app.V0laEditField.Value,...
                app.VdraEditField.Value, app.V0raEditField.Value, app.V0pcdEditField.Value,...
                app.KsysEditField.Value, app.Venk1EditField.Value, app.Venk2EditField.Value,...
                app.RaoEditField.Value, app.RsaEditField.Value, app.VusvEditField.Value,...
                app.CvTotalEditField.Value];
            
            %Saves matrix into csv file
            [file,path] = uiputfile('*.csv');
            filename = fullfile(path,file);
            csvwrite(filename,exportfile);
        end

        % Button pushed function: ImportSettingsButton_2
        function ImportSettingsButtonPushed(app, event)
            %Select file and import data
            [file,path] = uigetfile('*csv');
            filename = fullfile(path, file);
            data = importdata(filename);
            
            %Set data to input values             
            %Valves
            app.AVMaxOpeningAreacm2EditField.Value = data(1);
            app.AVLeakageAreacm2EditField.Value = data(2);
            app.MVMaxOpeningAreacm2EditField.Value = data(3);
            app.MVLeakageAreacm2EditField.Value = data(4);
            
            %Elastance
            app.EsaEditField_2.Value = data(5);
            app.EpaEditField_2.Value = data(6);
            app.EpuEditField_2.Value = data(7);
            app.EaoEditField_2.Value = data(8);
            app.EeslvfEditField_2.Value = data(9);
            app.EesrvfEditField_2.Value = data(10);
            app.EeslaEditField_2.Value = data(11);
            app.EesraEditField_2.Value = data(12);
            
            %Lambda
            app.lambdalvfEditField.Value = data(13);
            app.lambdarvfEditField.Value = data(14);
            app.lambdalaEditField.Value = data(15);
            app.lambdaraEditField.Value = data(16);
            app.lambdapcdEditField.Value = data(17);
            
            %Volume
            app.VdlvfEditField.Value = data(18);
            app.V0lvfEditField.Value = data(19);
            app.VdrvfEditField.Value = data(20);
            app.V0rvfEditField.Value = data(21);
            app.VdlaEditField.Value = data(22);
            app.V0laEditField.Value = data(23);
            app.VdraEditField.Value = data(24);
            app.V0raEditField.Value = data(25);
            app.V0pcdEditField.Value = data(26);
            
            %Other
            app.KsysEditField.Value = data(27);
            app.Venk1EditField.Value = data(28);
            app.Venk2EditField.Value = data(29);
            app.RaoEditField.Value = data(30);
            app.RsaEditField.Value = data(31);
            app.VusvEditField.Value = data(32);
            app.CvTotalEditField.Value = data(33);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Parameters1Tab = uitab(app.TabGroup);
            app.Parameters1Tab.Title = 'Parameters 1';
            app.ResetDefaultValuesButton = uibutton(app.Parameters1Tab, 'push');
            app.ResetDefaultValuesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDefaultValuesButtonPushed, true);
            app.ResetDefaultValuesButton.Text = 'Reset Default Values';
            app.PleaseEnterModelNameEditFieldLabel = uilabel(app.Parameters1Tab);
            app.PleaseEnterModelNameEditFieldLabel.Text = 'Please Enter Model Name:';
            app.PleaseEnterModelNameEditField = uieditfield(app.Parameters1Tab, 'text');
            app.PleaseEnterModelNameEditField.Value = 'Michaels_CVModel_fast8';
            app.ValvesPanel = uipanel(app.Parameters1Tab);
            app.ValvesPanel.Title = 'Valves';
            app.AVMaxOpeningAreacm2EditFieldLabel = uilabel(app.ValvesPanel);
            app.AVMaxOpeningAreacm2EditFieldLabel.Text = 'AV Max Opening Area (cm^2)';
            app.AVMaxOpeningAreacm2EditField = uieditfield(app.ValvesPanel, 'numeric');
            app.AVMaxOpeningAreacm2EditField.Value = 4;
            app.AVLeakageAreacm2EditFieldLabel = uilabel(app.ValvesPanel);
            app.AVLeakageAreacm2EditFieldLabel.Text = 'AV Leakage Area (cm^2)';
            app.AVLeakageAreacm2EditField = uieditfield(app.ValvesPanel, 'numeric');
            app.AVLeakageAreacm2EditField.Value = 1e-13;
            app.MVMaxOpeningAreacm2EditFieldLabel = uilabel(app.ValvesPanel);
            app.MVMaxOpeningAreacm2EditFieldLabel.Text = 'MV Max Opening Area (cm^2)';
            app.MVMaxOpeningAreacm2EditField = uieditfield(app.ValvesPanel, 'numeric');
            app.MVMaxOpeningAreacm2EditField.Value = 5;
            app.MVLeakageAreacm2EditFieldLabel = uilabel(app.ValvesPanel);
            app.MVLeakageAreacm2EditFieldLabel.Text = 'MV Leakage Area (cm^2)';
            app.MVLeakageAreacm2EditField = uieditfield(app.ValvesPanel, 'numeric');
            app.MVLeakageAreacm2EditField.Value = 1e-13;
            app.Label = uilabel(app.ValvesPanel);
            app.Label.Text = '4';
            app.e13Label = uilabel(app.ValvesPanel);
            app.e13Label.Text = '1e-13';
            app.Label_2 = uilabel(app.ValvesPanel);
            app.Label_2.Text = '5';
            app.e13Label_2 = uilabel(app.ValvesPanel);
            app.e13Label_2.Text = '1e-13';
            app.CalibrateSettingsPanel_2 = uipanel(app.Parameters1Tab);
            app.CalibrateSettingsPanel_2.Title = 'Calibrate Settings';
            app.ExportSettingsButton_2 = uibutton(app.CalibrateSettingsPanel_2, 'push');
            app.ExportSettingsButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportSettingsButtonPushed, true);
            app.ExportSettingsButton_2.Text = 'Export Settings';
            app.ImportSettingsButton_2 = uibutton(app.CalibrateSettingsPanel_2, 'push');
            app.ImportSettingsButton_2.ButtonPushedFcn = createCallbackFcn(app, @ImportSettingsButtonPushed, true);
            app.ImportSettingsButton_2.Text = 'Import Settings';
            app.Parameters2Tab = uitab(app.TabGroup);
            app.Parameters2Tab.Title = 'Parameters 2';
            app.LambdaPanel = uipanel(app.Parameters2Tab);
            app.LambdaPanel.Title = 'Lambda';
            app.lambdarvfEditFieldLabel = uilabel(app.LambdaPanel);
            app.lambdarvfEditFieldLabel.Text = 'lambdarvf';
            app.lambdarvfEditField = uieditfield(app.LambdaPanel, 'numeric');
            app.lambdarvfEditField.Value = 0.028;
            app.lambdalaEditFieldLabel = uilabel(app.LambdaPanel);
            app.lambdalaEditFieldLabel.Text = 'lambdala';
            app.lambdalaEditField = uieditfield(app.LambdaPanel, 'numeric');
            app.lambdalaEditField.Value = 0.13;
            app.lambdaraEditFieldLabel = uilabel(app.LambdaPanel);
            app.lambdaraEditFieldLabel.Text = 'lambdara';
            app.lambdaraEditField = uieditfield(app.LambdaPanel, 'numeric');
            app.lambdaraEditField.Value = 0.13;
            app.Label_11 = uilabel(app.LambdaPanel);
            app.Label_11.Text = '0.041';
            app.Label_15 = uilabel(app.LambdaPanel);
            app.Label_15.Text = '0.028';
            app.Label_12 = uilabel(app.LambdaPanel);
            app.Label_12.Text = '0.13';
            app.Label_16 = uilabel(app.LambdaPanel);
            app.Label_16.Text = '0.13';
            app.lambdapcdEditFieldLabel = uilabel(app.LambdaPanel);
            app.lambdapcdEditFieldLabel.Text = 'lambdapcd';
            app.lambdapcdEditField = uieditfield(app.LambdaPanel, 'numeric');
            app.lambdapcdEditField.Value = 0.0085;
            app.lambdalvfEditFieldLabel = uilabel(app.LambdaPanel);
            app.lambdalvfEditFieldLabel.Text = 'lambdalvf';
            app.lambdalvfEditField = uieditfield(app.LambdaPanel, 'numeric');
            app.lambdalvfEditField.Value = 0.041;
            app.Label_17 = uilabel(app.LambdaPanel);
            app.Label_17.Text = '0.0085';
            app.VolumePanel = uipanel(app.Parameters2Tab);
            app.VolumePanel.Title = 'Volume';
            app.V0lvfEditFieldLabel = uilabel(app.VolumePanel);
            app.V0lvfEditFieldLabel.Text = 'V0lvf';
            app.V0lvfEditField = uieditfield(app.VolumePanel, 'numeric');
            app.V0lvfEditField.Value = 40;
            app.VdrvfEditFieldLabel = uilabel(app.VolumePanel);
            app.VdrvfEditFieldLabel.Text = 'Vdrvf';
            app.VdrvfEditField = uieditfield(app.VolumePanel, 'numeric');
            app.VdrvfEditField.Value = 40.8;
            app.V0rvfEditFieldLabel = uilabel(app.VolumePanel);
            app.V0rvfEditFieldLabel.Text = 'V0rvf';
            app.V0rvfEditField = uieditfield(app.VolumePanel, 'numeric');
            app.V0rvfEditField.Value = 50;
            app.Label_13 = uilabel(app.VolumePanel);
            app.Label_13.Text = '20';
            app.Label_18 = uilabel(app.VolumePanel);
            app.Label_18.Text = '40';
            app.Label_14 = uilabel(app.VolumePanel);
            app.Label_14.Text = '40.8';
            app.Label_19 = uilabel(app.VolumePanel);
            app.Label_19.Text = '50';
            app.VdlaEditFieldLabel = uilabel(app.VolumePanel);
            app.VdlaEditFieldLabel.Text = 'Vdla';
            app.VdlaEditField = uieditfield(app.VolumePanel, 'numeric');
            app.VdlaEditField.Value = 10;
            app.VdlvfEditFieldLabel = uilabel(app.VolumePanel);
            app.VdlvfEditFieldLabel.Text = 'Vdlvf';
            app.VdlvfEditField = uieditfield(app.VolumePanel, 'numeric');
            app.VdlvfEditField.Value = 20;
            app.V0laEditFieldLabel = uilabel(app.VolumePanel);
            app.V0laEditFieldLabel.Text = 'V0la';
            app.V0laEditField = uieditfield(app.VolumePanel, 'numeric');
            app.V0laEditField.Value = 20;
            app.VdraEditFieldLabel = uilabel(app.VolumePanel);
            app.VdraEditFieldLabel.Text = 'Vdra';
            app.VdraEditField = uieditfield(app.VolumePanel, 'numeric');
            app.VdraEditField.Value = 10;
            app.V0raEditFieldLabel = uilabel(app.VolumePanel);
            app.V0raEditFieldLabel.Text = 'V0ra';
            app.V0raEditField = uieditfield(app.VolumePanel, 'numeric');
            app.V0raEditField.Value = 20;
            app.V0pcdEditFieldLabel = uilabel(app.VolumePanel);
            app.V0pcdEditFieldLabel.Text = 'V0pcd';
            app.V0pcdEditField = uieditfield(app.VolumePanel, 'numeric');
            app.V0pcdEditField.Value = 200;
            app.Label_20 = uilabel(app.VolumePanel);
            app.Label_20.Text = '10';
            app.Label_21 = uilabel(app.VolumePanel);
            app.Label_21.Text = '20';
            app.Label_22 = uilabel(app.VolumePanel);
            app.Label_22.Text = '10';
            app.Label_23 = uilabel(app.VolumePanel);
            app.Label_23.Text = '20';
            app.Label_24 = uilabel(app.VolumePanel);
            app.Label_24.Text = '200';
            app.ResetDefaultValuesButton_2 = uibutton(app.Parameters2Tab, 'push');
            app.ResetDefaultValuesButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetDefaultValuesButton_2Pushed, true);
            app.ResetDefaultValuesButton_2.Text = 'Reset Default Values';
            app.Parameters3Tab = uitab(app.TabGroup);
            app.Parameters3Tab.Title = 'Parameters 3';
            app.OtherPanel = uipanel(app.Parameters3Tab);
            app.OtherPanel.Title = 'Other';
            app.Venk1EditFieldLabel = uilabel(app.OtherPanel);
            app.Venk1EditFieldLabel.Text = 'Venk1';
            app.Venk1EditField = uieditfield(app.OtherPanel, 'numeric');
            app.Venk1EditField.Value = 0.5;
            app.Venk2EditFieldLabel = uilabel(app.OtherPanel);
            app.Venk2EditFieldLabel.Text = 'Venk2';
            app.Venk2EditField = uieditfield(app.OtherPanel, 'numeric');
            app.Venk2EditField.Value = 0.5;
            app.RaoEditFieldLabel = uilabel(app.OtherPanel);
            app.RaoEditFieldLabel.Text = 'Rao';
            app.RaoEditField = uieditfield(app.OtherPanel, 'numeric');
            app.RaoEditField.Value = 0.2;
            app.Label_25 = uilabel(app.OtherPanel);
            app.Label_25.Text = '0.075';
            app.Label_26 = uilabel(app.OtherPanel);
            app.Label_26.Text = '0.5';
            app.Label_27 = uilabel(app.OtherPanel);
            app.Label_27.Text = '0.5';
            app.Label_28 = uilabel(app.OtherPanel);
            app.Label_28.Text = '0.2';
            app.RsaEditFieldLabel = uilabel(app.OtherPanel);
            app.RsaEditFieldLabel.Text = 'Rsa';
            app.RsaEditField = uieditfield(app.OtherPanel, 'numeric');
            app.RsaEditField.Value = 0.74;
            app.KsysEditFieldLabel = uilabel(app.OtherPanel);
            app.KsysEditFieldLabel.Text = 'Ksys';
            app.KsysEditField = uieditfield(app.OtherPanel, 'numeric');
            app.KsysEditField.Value = 0.075;
            app.VusvEditFieldLabel = uilabel(app.OtherPanel);
            app.VusvEditFieldLabel.Text = 'Vusv';
            app.VusvEditField = uieditfield(app.OtherPanel, 'numeric');
            app.VusvEditField.Value = 1976.1;
            app.CvTotalEditFieldLabel = uilabel(app.OtherPanel);
            app.CvTotalEditFieldLabel.Text = 'CvTotal';
            app.CvTotalEditField = uieditfield(app.OtherPanel, 'numeric');
            app.CvTotalEditField.Value = 5200;
            app.Label_29 = uilabel(app.OtherPanel);
            app.Label_29.Text = '0.74';
            app.Label_30 = uilabel(app.OtherPanel);
            app.Label_30.Text = '1976';
            app.Label_31 = uilabel(app.OtherPanel);
            app.Label_31.Text = '5200';
            app.ResetDefaultValuesButton_3 = uibutton(app.Parameters3Tab, 'push');
            app.ResetDefaultValuesButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetDefaultValuesButton_3Pushed, true);
            app.ResetDefaultValuesButton_3.Text = 'Reset Default Values';
            app.ElastancePanel_2 = uipanel(app.Parameters3Tab);
            app.ElastancePanel_2.Title = 'Elastance';
            app.EpaEditField_2Label = uilabel(app.ElastancePanel_2);
            app.EpaEditField_2Label.Text = 'Epa';
            app.EpaEditField_2 = uieditfield(app.ElastancePanel_2, 'numeric');
            app.EpaEditField_2.Value = 0.15;
            app.EpuEditField_2Label = uilabel(app.ElastancePanel_2);
            app.EpuEditField_2Label.Text = 'Epu';
            app.EpuEditField_2 = uieditfield(app.ElastancePanel_2, 'numeric');
            app.EpuEditField_2.Value = 0.04;
            app.EaoEditField_2Label = uilabel(app.ElastancePanel_2);
            app.EaoEditField_2Label.Text = 'Eao';
            app.EaoEditField_2 = uieditfield(app.ElastancePanel_2, 'numeric');
            app.EaoEditField_2.Value = 1.04;
            app.EeslvfEditField_2Label = uilabel(app.ElastancePanel_2);
            app.EeslvfEditField_2Label.Text = 'Eeslvf';
            app.EeslvfEditField_2 = uieditfield(app.ElastancePanel_2, 'numeric');
            app.EeslvfEditField_2.Value = 5;
            app.EesrvfEditField_2Label = uilabel(app.ElastancePanel_2);
            app.EesrvfEditField_2Label.Text = 'Eesrvf';
            app.EesrvfEditField_2 = uieditfield(app.ElastancePanel_2, 'numeric');
            app.EesrvfEditField_2.Value = 1.75;
            app.EeslaEditField_2Label = uilabel(app.ElastancePanel_2);
            app.EeslaEditField_2Label.Text = 'Eesla';
            app.EeslaEditField_2 = uieditfield(app.ElastancePanel_2, 'numeric');
            app.EeslaEditField_2.Value = 0.63;
            app.EesraEditField_2Label = uilabel(app.ElastancePanel_2);
            app.EesraEditField_2Label.Text = 'Eesra';
            app.EesraEditField_2 = uieditfield(app.ElastancePanel_2, 'numeric');
            app.EesraEditField_2.Value = 0.63;
            app.EsaEditField_2Label = uilabel(app.ElastancePanel_2);
            app.EsaEditField_2Label.Text = 'Esa';
            app.EsaEditField_2 = uieditfield(app.ElastancePanel_2, 'numeric');
            app.EsaEditField_2.Value = 0.37;
            app.Label_32 = uilabel(app.ElastancePanel_2);
            app.Label_32.Text = '0.37';
            app.Label_33 = uilabel(app.ElastancePanel_2);
            app.Label_33.Text = '0.15';
            app.Label_34 = uilabel(app.ElastancePanel_2);
            app.Label_34.Text = '0.04';
            app.Label_35 = uilabel(app.ElastancePanel_2);
            app.Label_35.Text = '1.04';
            app.Label_36 = uilabel(app.ElastancePanel_2);
            app.Label_36.Text = '5';
            app.Label_37 = uilabel(app.ElastancePanel_2);
            app.Label_37.Text = '1.75';
            app.Label_38 = uilabel(app.ElastancePanel_2);
            app.Label_38.Text = '0.63';
            app.Label_39 = uilabel(app.ElastancePanel_2);
            app.Label_39.Text = '0.63';
            app.Results1Tab = uitab(app.TabGroup);
            app.Results1Tab.Title = 'Results 1';
            app.ApplyButton_2 = uibutton(app.Results1Tab, 'push');
            app.ApplyButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_2Pushed, true);
            app.ApplyButton_2.Text = 'Apply';
            app.P_RVEDPTextAreaLabel = uilabel(app.Results1Tab);
            app.P_RVEDPTextAreaLabel.Text = 'P_RVEDP';
            app.P_RVEDPTextArea = uitextarea(app.Results1Tab);
            app.P_RVmaxTextAreaLabel = uilabel(app.Results1Tab);
            app.P_RVmaxTextAreaLabel.Text = 'P_RVmax';
            app.P_RVmaxTextArea = uitextarea(app.Results1Tab);
            app.RVESVTextAreaLabel = uilabel(app.Results1Tab);
            app.RVESVTextAreaLabel.Text = 'RVESV';
            app.RVESVTextArea = uitextarea(app.Results1Tab);
            app.RVEDVTextAreaLabel = uilabel(app.Results1Tab);
            app.RVEDVTextAreaLabel.Text = 'RVEDV';
            app.RVEDVTextArea = uitextarea(app.Results1Tab);
            app.RV_SVTextAreaLabel = uilabel(app.Results1Tab);
            app.RV_SVTextAreaLabel.Text = 'RV_SV';
            app.RV_SVTextArea = uitextarea(app.Results1Tab);
            app.RV_EFTextAreaLabel = uilabel(app.Results1Tab);
            app.RV_EFTextAreaLabel.Text = 'RV_EF';
            app.RV_EFTextArea = uitextarea(app.Results1Tab);
            app.P_RVEDP1TextAreaLabel = uilabel(app.Results1Tab);
            app.P_RVEDP1TextAreaLabel.Text = 'P_RVEDP1';
            app.P_RVEDP1TextArea = uitextarea(app.Results1Tab);
            app.P_RVmax1TextAreaLabel = uilabel(app.Results1Tab);
            app.P_RVmax1TextAreaLabel.Text = 'P_RVmax1';
            app.P_RVmax1TextArea = uitextarea(app.Results1Tab);
            app.P_RAminTextAreaLabel = uilabel(app.Results1Tab);
            app.P_RAminTextAreaLabel.Text = 'P_RAmin';
            app.P_RAminTextArea = uitextarea(app.Results1Tab);
            app.P_RAmaxTextAreaLabel = uilabel(app.Results1Tab);
            app.P_RAmaxTextAreaLabel.Text = 'P_RAmax';
            app.P_RAmaxTextArea = uitextarea(app.Results1Tab);
            app.Vol_RAminTextAreaLabel = uilabel(app.Results1Tab);
            app.Vol_RAminTextAreaLabel.Text = 'Vol_RAmin';
            app.Vol_RAminTextArea = uitextarea(app.Results1Tab);
            app.Vol_RAmaxTextAreaLabel = uilabel(app.Results1Tab);
            app.Vol_RAmaxTextAreaLabel.Text = 'Vol_RAmax';
            app.Vol_RAmaxTextArea = uitextarea(app.Results1Tab);
            app.AVATextAreaLabel = uilabel(app.Results1Tab);
            app.AVATextAreaLabel.Text = 'AVA';
            app.AVATextArea = uitextarea(app.Results1Tab);
            app.MR_EROTextAreaLabel = uilabel(app.Results1Tab);
            app.MR_EROTextAreaLabel.Text = 'MR_ERO';
            app.MR_EROTextArea = uitextarea(app.Results1Tab);
            app.LVETTextAreaLabel = uilabel(app.Results1Tab);
            app.LVETTextAreaLabel.Text = 'LVET';
            app.LVETTextArea = uitextarea(app.Results1Tab);
            app.P_LAminTextAreaLabel = uilabel(app.Results1Tab);
            app.P_LAminTextAreaLabel.Text = 'P_LAmin';
            app.P_LAminTextArea = uitextarea(app.Results1Tab);
            app.P_LAmaxTextAreaLabel = uilabel(app.Results1Tab);
            app.P_LAmaxTextAreaLabel.Text = 'P_LAmax';
            app.P_LAmaxTextArea = uitextarea(app.Results1Tab);
            app.meanLAPTextAreaLabel = uilabel(app.Results1Tab);
            app.meanLAPTextAreaLabel.Text = 'meanLAP';
            app.meanLAPTextArea = uitextarea(app.Results1Tab);
            app.Vol_LAminTextAreaLabel = uilabel(app.Results1Tab);
            app.Vol_LAminTextAreaLabel.Text = 'Vol_LAmin';
            app.Vol_LAminTextArea = uitextarea(app.Results1Tab);
            app.Vol_LAmaxTextAreaLabel = uilabel(app.Results1Tab);
            app.Vol_LAmaxTextAreaLabel.Text = 'Vol_LAmax';
            app.Vol_LAmaxTextArea = uitextarea(app.Results1Tab);
            app.Results2Tab = uitab(app.TabGroup);
            app.Results2Tab.Title = 'Results 2';
            app.DBPTextAreaLabel = uilabel(app.Results2Tab);
            app.DBPTextAreaLabel.Text = 'DBP';
            app.DBPTextArea = uitextarea(app.Results2Tab);
            app.SBPTextAreaLabel = uilabel(app.Results2Tab);
            app.SBPTextAreaLabel.Text = 'SBP';
            app.SBPTextArea = uitextarea(app.Results2Tab);
            app.P_LVEDPTextAreaLabel = uilabel(app.Results2Tab);
            app.P_LVEDPTextAreaLabel.Text = 'P_LVEDP';
            app.P_LVEDPTextArea = uitextarea(app.Results2Tab);
            app.P_LVmaxTextAreaLabel = uilabel(app.Results2Tab);
            app.P_LVmaxTextAreaLabel.Text = 'P_LVmax';
            app.P_LVmaxTextArea = uitextarea(app.Results2Tab);
            app.mLVEDPTextAreaLabel = uilabel(app.Results2Tab);
            app.mLVEDPTextAreaLabel.Text = 'mLVEDP';
            app.mLVEDPTextArea = uitextarea(app.Results2Tab);
            app.LVESVTextAreaLabel = uilabel(app.Results2Tab);
            app.LVESVTextAreaLabel.Text = 'LVESV';
            app.LVESVTextArea = uitextarea(app.Results2Tab);
            app.LVEDVTextAreaLabel = uilabel(app.Results2Tab);
            app.LVEDVTextAreaLabel.Text = 'LVEDV';
            app.LVEDVTextArea = uitextarea(app.Results2Tab);
            app.LV_SVTextAreaLabel = uilabel(app.Results2Tab);
            app.LV_SVTextAreaLabel.Text = 'LV_SV';
            app.LV_SVTextArea = uitextarea(app.Results2Tab);
            app.AVOF_SVTextAreaLabel = uilabel(app.Results2Tab);
            app.AVOF_SVTextAreaLabel.Text = 'AVOF_SV';
            app.AVOF_SVTextArea = uitextarea(app.Results2Tab);
            app.LV_EFTextAreaLabel = uilabel(app.Results2Tab);
            app.LV_EFTextAreaLabel.Text = 'LV_EF';
            app.LV_EFTextArea = uitextarea(app.Results2Tab);
            app.AVOF_EFTextAreaLabel = uilabel(app.Results2Tab);
            app.AVOF_EFTextAreaLabel.Text = 'AVOF_EF';
            app.AVOF_EFTextArea = uitextarea(app.Results2Tab);
            app.CO_LVTextAreaLabel = uilabel(app.Results2Tab);
            app.CO_LVTextAreaLabel.Text = 'CO_LV';
            app.CO_LVTextArea = uitextarea(app.Results2Tab);
            app.CO_AVOFTextAreaLabel = uilabel(app.Results2Tab);
            app.CO_AVOFTextAreaLabel.Text = 'CO_AVOF';
            app.CO_AVOFTextArea = uitextarea(app.Results2Tab);
            app.TSWTextAreaLabel = uilabel(app.Results2Tab);
            app.TSWTextAreaLabel.Text = 'TSW';
            app.TSWTextArea = uitextarea(app.Results2Tab);
            app.Pw_TSWTextAreaLabel = uilabel(app.Results2Tab);
            app.Pw_TSWTextAreaLabel.Text = 'Pw_TSW';
            app.Pw_TSWTextArea = uitextarea(app.Results2Tab);
            app.ApplyButton_3 = uibutton(app.Results2Tab, 'push');
            app.ApplyButton_3.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_2Pushed, true);
            app.ApplyButton_3.Text = 'Apply';
            app.Results3Tab = uitab(app.TabGroup);
            app.Results3Tab.Title = 'Results 3';
            app.QmaxAVTextAreaLabel = uilabel(app.Results3Tab);
            app.QmaxAVTextAreaLabel.Text = 'QmaxAV';
            app.QmaxAVTextArea = uitextarea(app.Results3Tab);
            app.QmaxLVOTTextAreaLabel = uilabel(app.Results3Tab);
            app.QmaxLVOTTextAreaLabel.Text = 'QmaxLVOT';
            app.QmaxLVOTTextArea = uitextarea(app.Results3Tab);
            app.VmaxAVTextAreaLabel = uilabel(app.Results3Tab);
            app.VmaxAVTextAreaLabel.Text = 'VmaxAV';
            app.VmaxAVTextArea = uitextarea(app.Results3Tab);
            app.VmaxLVOTTextAreaLabel = uilabel(app.Results3Tab);
            app.VmaxLVOTTextAreaLabel.Text = 'VmaxLVOT';
            app.VmaxLVOTTextArea = uitextarea(app.Results3Tab);
            app.MPGTextAreaLabel = uilabel(app.Results3Tab);
            app.MPGTextAreaLabel.Text = 'MPG';
            app.MPGTextArea = uitextarea(app.Results3Tab);
            app.MaxPGTextAreaLabel = uilabel(app.Results3Tab);
            app.MaxPGTextAreaLabel.Text = 'MaxPG';
            app.MaxPGTextArea = uitextarea(app.Results3Tab);
            app.DVITextAreaLabel = uilabel(app.Results3Tab);
            app.DVITextAreaLabel.Text = 'DVI';
            app.DVITextArea = uitextarea(app.Results3Tab);
            app.ZvaTextAreaLabel = uilabel(app.Results3Tab);
            app.ZvaTextAreaLabel.Text = 'Zva';
            app.ZvaTextArea = uitextarea(app.Results3Tab);
            app.Vol_MVTextAreaLabel = uilabel(app.Results3Tab);
            app.Vol_MVTextAreaLabel.Text = 'Vol_MV';
            app.Vol_MVTextArea = uitextarea(app.Results3Tab);
            app.Vol_MRTextAreaLabel = uilabel(app.Results3Tab);
            app.Vol_MRTextAreaLabel.Text = 'Vol_MR';
            app.Vol_MRTextArea = uitextarea(app.Results3Tab);
            app.Reg_FracTextAreaLabel = uilabel(app.Results3Tab);
            app.Reg_FracTextAreaLabel.Text = 'Reg_Frac';
            app.Reg_FracTextArea = uitextarea(app.Results3Tab);
            app.MV_timeTextAreaLabel = uilabel(app.Results3Tab);
            app.MV_timeTextAreaLabel.Text = 'MV_time';
            app.MV_timeTextArea = uitextarea(app.Results3Tab);
            app.MR_timeTextAreaLabel = uilabel(app.Results3Tab);
            app.MR_timeTextAreaLabel.Text = 'MR_time';
            app.MR_timeTextArea = uitextarea(app.Results3Tab);
            app.ApplyButton_4 = uibutton(app.Results3Tab, 'push');
            app.ApplyButton_4.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_2Pushed, true);
            app.ApplyButton_4.Text = 'Apply';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CVModelGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end